classdef tutorial_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadandSavePanel                matlab.ui.container.Panel
        SaveNetworkandQuitButton        matlab.ui.control.Button
        CurrentConfigFileEditField      matlab.ui.control.EditField
        CurrentConfigFileEditFieldLabel  matlab.ui.control.Label
        SelectNetworkConfigFileButton   matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        NetworkTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        SingleCellTab                   matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        NodesTab                        matlab.ui.container.Tab
        EditNodePanel                   matlab.ui.container.Panel
        NodeTypeEditField               matlab.ui.control.EditField
        NodeTypeEditFieldLabel          matlab.ui.control.Label
        PlotAPButton                    matlab.ui.control.Button
        NodeNumberEditField             matlab.ui.control.EditField
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        AttributeValueEditField         matlab.ui.control.NumericEditField
        AttributeValueEditFieldLabel    matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        AttributeDropDown               matlab.ui.control.DropDown
        AttributeDropDownLabel          matlab.ui.control.Label
        SelectNodeButton_2              matlab.ui.control.Button
        NodeDeletionPanel               matlab.ui.container.Panel
        DeleteNodeButton                matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        SelectNodeButton                matlab.ui.control.Button
        NodeCreationPanel               matlab.ui.container.Panel
        NodeNameEditField               matlab.ui.control.EditField
        NodeNameEditFieldLabel          matlab.ui.control.Label
        CancelButton_2                  matlab.ui.control.Button
        SaveNodeButton                  matlab.ui.control.Button
        NodePresetsDropDown             matlab.ui.control.DropDown
        NodePresetsDropDownLabel        matlab.ui.control.Label
        NodeTypeDropDown                matlab.ui.control.DropDown
        NodeTypeDropDownLabel           matlab.ui.control.Label
        SelectLocationButton            matlab.ui.control.Button
        PathsTab                        matlab.ui.container.Tab
        PathCreationPanel               matlab.ui.container.Panel
        PathPresetDropDown              matlab.ui.control.DropDown
        PathPresetDropDownLabel         matlab.ui.control.Label
        CancelPathCreationButton        matlab.ui.control.Button
        SavePathButton                  matlab.ui.control.Button
        SelectNode2Button               matlab.ui.control.Button
        SelectNode1Button               matlab.ui.control.Button
        Node2jEditField_2               matlab.ui.control.EditField
        Node2jLabel_2                   matlab.ui.control.Label
        Node1iEditField_2               matlab.ui.control.EditField
        Node1iLabel_2                   matlab.ui.control.Label
        PathDeletionPanel               matlab.ui.container.Panel
        CancelPathDeletionButton        matlab.ui.control.Button
        DeletePathButton                matlab.ui.control.Button
        SelectPathButton                matlab.ui.control.Button
        EditPathPanel                   matlab.ui.container.Panel
        ResetButton_2                   matlab.ui.control.Button
        AttributeValueEditField_2       matlab.ui.control.NumericEditField
        AttributeValueEditField_2Label  matlab.ui.control.Label
        AttributeDropDown_2             matlab.ui.control.DropDown
        AttributeDropDown_2Label        matlab.ui.control.Label
        UpdateButton_2                  matlab.ui.control.Button
        SelectPathButton_2              matlab.ui.control.Button
        Node2jEditField                 matlab.ui.control.EditField
        Node2jLabel                     matlab.ui.control.Label
        Node1iEditField                 matlab.ui.control.EditField
        Node1iLabel                     matlab.ui.control.Label
        ProbesTab                       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        newnodebool % Boolean for creating a node
        delnodebool % Boolean for deleting a node
        editnodebool % Boolean for editing a node
        tooltipObj % object that corresponds to the node tooltip
        editnodeind % index for node to edit
        delnodeind % index for node to delete
        node1ind % index for path creation node i
        node2ind % index for path creation node j
        node1bool % Boolean for selecting node j for path creation
        node2bool % Boolean for selecting node j for path creation
        nodeTypesAttr % attributes which correspond to each unique cell type
        Node_pos % The coordinates of the nodes loaded in
        node_connections % Linkages for node arrays
        path_plot % physical lines corresponding to the paths in the model
        path_connections % linkages for path arrays
        node_pos_plot % scatter plot for the nodes
        probe_pos_plot % scatter plot for the probes
        Probe_pos % The coordinates for the probes loaded in
        raw_nodes % node attributes the full network things
        raw_nodes_reserve % reserve for going to original settings
        raw_paths % path attributes the full network things
        raw_paths_reserve % reserve for going to original settings
        raw_probes % probe attributes for the network
        node_presets % node presets for creating a new node
        path_presets % path presets for creating a new path
        unique_node_idx % indexing corresponding with the node presets
        unique_path_idx % indexing corresponding with the path presets
        editpathind % edit path index
        path_ind_visual % temp for path historic
        node_text % check to solve
        unique_node_presets % front end structure for node presets
        node_preset_dict % Back end dictionary for node presets
    end    
    
    methods (Access = private)
        
                
        function d = point_to_line(app, pt, v1, v2)
            % TO ADD: 3D capability functionality (shorten it)
            % Euclidean distance to the midpoint of the line  
            midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2))];
            %midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2)) 0.5*(v1(3)+v2(3))];
            d = pdist([pt;midpoint],'euclidean');
        end
        
        function index = nearest_line(app,pt)
            minimum_val = 1000000;
            if size(app.raw_paths,1) == 0 
                index = 0;
                return
            end
            for i = 2:size(app.raw_paths,1)
                [node1idx,~] = find(app.node_connections.' == app.raw_paths{i,3});
                [node2idx,~] = find(app.node_connections.' == app.raw_paths{i,4});
                %curr_dist = app.point_to_line(pt,app.Node_pos(node1idx,1:3),app.Node_pos(node2idx,1:3));
                curr_dist = app.point_to_line(pt,app.Node_pos(node1idx,1:2),app.Node_pos(node2idx,1:2));
                if curr_dist < minimum_val
                    minimum_val = curr_dist;
                    index = i-1;        
                end
            end
        end
        
        function val = unique_num(app,array)
            B = sort(array);
            for i=1:length(B)
                if i ~= B(i)
                    val = i;
                    return
                end
            end
            val = B(end)+1;
        end
        
        function event_handle(app,event,a1,a2,a3,a4,a5,a6,a7,a8,a9,a10)
            % Dummy function to pass clicking the plot elements into the
            % axes button down function
            app.UIAxesButtonDown(event)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
            disp('Startup initiated')
            hold(app.UIAxes,'on')
            app.node_pos_plot = scatter(app.UIAxes,[],[],75,'filled',...
                'LineWidth',2,'Marker','o','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);
            app.probe_pos_plot = scatter(app.UIAxes,[],[],'LineWidth',1.5,...
                'Marker','d','CData',[0 0 0],'ButtonDownFcn',@app.event_handle);

            xlsx_file = 'Heart_N3_second.xlsx';
            [~,~,app.raw_nodes]=xlsread(xlsx_file,'Node_second');
            [~,~,app.raw_paths]=xlsread(xlsx_file,'Path_second');
            [~,~,app.raw_probes]=xlsread(xlsx_file,'Probe');
            app.raw_nodes(:,end-2) = [];
            app.raw_nodes(end,:) = [];
            app.raw_nodes_reserve = app.raw_nodes;
            app.raw_paths_reserve = app.raw_paths;
            app.nodeTypesAttr = dictionary(["N", "M", "NM"],{app.raw_nodes(1,4:24),app.raw_nodes(1,25:47),app.raw_nodes(1,4:47)});
            node_temp = cell2mat(app.raw_nodes(2:end,4:end));         
            
            node_temp(:,45:46)=40000;
            node_temp(isnan(node_temp))=40000;
            [app.node_presets,app.unique_node_idx] = unique(node_temp,'rows');
            app.node_presets = changem(app.node_presets,NaN,40000);
            app.unique_node_idx = app.unique_node_idx +1;
            % Dictionary for the drop down menu
            app.unique_node_presets = struct('N', [], 'M', [], 'NM', []);
            % Back end dictionary for saving the correct preset
            app.node_preset_dict = dictionary;
            for idx = 1:length(app.unique_node_idx)
                curr =app.raw_nodes_reserve{app.unique_node_idx(idx),1};
                if strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"N")
                    app.unique_node_presets.N{end+1} = curr;

                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"M")
                    app.unique_node_presets.M{end+1} = curr;
                elseif strcmp(app.raw_nodes_reserve{app.unique_node_idx(idx),2},"NM")              
                    app.unique_node_presets.NM{end+1} = curr;
                end
                app.node_preset_dict(curr) = {app.node_presets(idx,:)};
            end

            path_temp = cell2mat(app.raw_paths_reserve(2:55,5:15));
            [app.path_presets,app.unique_path_idx] = unique(path_temp,'rows');
            app.unique_path_idx = app.unique_path_idx +1;

            NumPaths = size(cell2mat(app.raw_paths(2:end,4)),1);
            NumNodes = size(cell2mat(app.raw_nodes(2:end,4)),1);
            app.Node_pos= cell2mat(app.raw_nodes(2:end,end-3:end-2));
            app.Probe_pos= cell2mat(app.raw_probes(2:end,3:4));
            app.UIAxes.XLim = [round(min(app.Node_pos(:,1)),-1)-10 round(max(app.Node_pos(:,1)),-1)+10];
            app.UIAxes.YLim = [round(min(app.Node_pos(:,2)),-1)-10 round(max(app.Node_pos(:,2)),-1)+10];
            app.node_text = [];
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                app.node_pos_plot.XData = app.Node_pos(:,1);
                app.node_pos_plot.YData = app.Node_pos(:,2);
                %app.node_pos_plot.ZData = app.Node_pos(:,q);
                app.node_pos_plot.CData = zeros(NumNodes,3);
                
                for n=1:NumNodes
                    app.node_text(n) = text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),...
                        'Color','blue','FontSize',12,'tag',sprintf('cell%i',n),...
                        'ButtonDownFcn',@app.event_handle);
                end
                app.node_connections = 1:NumNodes;
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                app.probe_pos_plot.XData = app.Probe_pos(:,1);
                app.probe_pos_plot.YData = app.Probe_pos(:,2);
                %app.probe_pos_plot.ZData = app.Probe_pos(:,q);
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_plot = [];
            % IMPORTANT HERE: DETERMINE HOW TO MAKE THE PATHS 3D IF NEEDED AT ALL
            app.path_connections = [1:NumPaths.'; cell2mat(app.raw_paths(2:end,3)).'; cell2mat(app.raw_paths(2:end,4)).'].';
            
            for n=1:NumPaths
                app.path_plot(n)=line([app.Node_pos(app.path_connections(n,2),1),app.Node_pos(app.path_connections(n,3),1)],...
                    [app.Node_pos(app.path_connections(n,2),2),app.Node_pos(app.path_connections(n,3),2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n),'ButtonDownFcn',@app.event_handle);
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            I = imread('heartoutline.jpg'); 
            x = [20 180];
            y = [28 192];
            I = flipdim(I, 1);
            h= image(app.UIAxes,x,y,I,'ButtonDownFcn',@app.event_handle); 
            uistack(h,'bottom')
        end

        % Button pushed function: SelectLocationButton
        function selectLocation(app, event)
            app.newnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            cursor = app.UIAxes.CurrentPoint;
            x = cursor(1,1);
            y = cursor(1,2);
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            delete(app.tooltipObj)
            % Check that the mouse click is within the Axis
            if (x > min(xLimits) && x < max(xLimits) && ...
                y > min(yLimits) && y < max(yLimits))
                if ~isempty(app.Node_pos)
                    [~,dist1] = dsearchn([x,y],app.Node_pos);
                    [~,ind1]=min(dist1);
                    str = append('Node: ',int2str(app.raw_nodes{ind1+1,3}),' = ', app.raw_nodes(ind1+1,1));
                    if strcmp('N',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: N'];
                        back_color = [1 1 .3];
                    elseif strcmp('NM',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: NM'];
                        back_color = [1 .8 .4];
                    elseif strcmp('M',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: M'];
                        back_color = [1 .8 .8];
                    end
                    app.tooltipObj = text(app.UIAxes,x+5,y+5,str,...
                        'backgroundcolor',back_color,'tag','mytooltip','edgecolor',[0 0 0],...
                        'hittest','off');
                else 
                    ind1 = 0;
                    app.newnodebool = false;
                    app.delnodebool = false;
                    app.editnodebool = false;
                end
                if app.newnodebool
                    disp('new node')
                    app.newnodebool = false;
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.SaveNodeButton.Enable = "on";
                    app.CancelButton_2.Enable = "on";
                    delete(app.tooltipObj)

                    c = get(app.node_pos_plot,'CData');
                    x_temp = get(app.node_pos_plot,'XData');
                    y_temp = get(app.node_pos_plot,'YData');
                    x_temp(end+1) = x;
                    y_temp(end+1) = y;
                    c(end+1,:) = [0 1 0];
                    set(app.node_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
                elseif app.delnodebool
                    disp('delete selection')
                    app.delnodebool = false;
                    app.delnodeind = ind1;
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.DeleteNodeButton.Enable = "on";
                    app.CancelButton.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [1 0 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.delnodeind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                elseif app.editnodebool
                    disp('edit node')
                    app.editnodebool = false;
                    app.editnodeind = ind1;
                    app.AttributeDropDown.Items = app.nodeTypesAttr{app.raw_nodes(ind1+1,2)};
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [0 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    % Changing the previously selected node if it exists
                    if q 
                        if idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color; 
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    set(app.NodeNumberEditField,'Value',sprintf('%s (%i)',app.raw_nodes{app.editnodeind+1,1}, ind1));
                    set(app.NodeTypeEditField,'Value',app.raw_nodes{app.editnodeind+1,2})
                    exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
                    idx = find(exact_match);
                    set(app.AttributeValueEditField,'Value',app.raw_nodes{app.editnodeind+1,idx});
                elseif app.node1bool
                    app.node1ind = ind1;
                    c = get(app.node_pos,'CData');
                    color = [1 1 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.node1ind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    set(app.node1path, 'String', sprintf('%s (%i)',app.raw_nodes{app.node1ind+1,1},ind1));        
                    app.SelectNode2Button.Enable = "on";
                    app.SelectNode1Button.Enable = "on";
                    app.CancelPathCreationButton.Enable = "on";
                    p = findobj('tag','cancelpath');
                    p.Enable = 'on';       
                    app.node1bool = false;

                elseif app.node2bool
                    app.node2ind = ind1;
                    c = get(app.node_pos,'CData');
                    color = [1 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.node2ind previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    set(app.node2path, 'String', sprintf('%s (%i)',app.raw_nodes{app.node2ind+1,1},ind1));
                    app.SelectNode2Button.Enable = "on";
                    app.SelectNode1Button.Enable = "on";
                    app.CancelPathCreationButton.Enable = "on";
                    app.node2bool = false;

                end
                drawnow update;
            end
            
        end

        % Button pushed function: SelectNodeButton
        function selectNodeDel(app, event)
            app.delnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button pushed function: SelectNodeButton_2
        function selectNodeEdit(app, event)
            app.editnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Callback function: NodeTypeDropDown, NodeTypeDropDown, 
        % ...and 1 other component
        function presetChange(app, event)
            value = app.NodeTypeDropDown.Value;
            if strcmp(value,"N")
                app.NodePresetsDropDown.Items = app.unique_node_presets.N;            
            elseif strcmp(value,"M")
                app.NodePresetsDropDown.Items = app.unique_node_presets.M;            
            elseif strcmp(value,"NM")
                app.NodePresetsDropDown.Items = app.unique_node_presets.NM;            
            end
            
        end

        % Button pushed function: SaveNodeButton
        function saveNode(app, event)
            
            % Check that the node name is unique           
            if ismember(app.NodeNameEditField.Value,app.raw_nodes(:,1))
                f =msgbox("Cell Name already exists","Error","error");
                waitfor(f);
                return
            elseif strcmp(app.NodeNameEditField.Value,app.NodeNameEditField.Placeholder)
                f =msgbox("Enter a Cell Name","Error","error");
                waitfor(f);
                return
            end
            app.SelectLocationButton.Enable = "on";
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
            c = get(app.node_pos_plot,'CData');
            x_temp = get(app.node_pos_plot,'XData');
            y_temp = get(app.node_pos_plot,'YData');
            x = x_temp(end);
            y = y_temp(end);
            app.Node_pos(end+1,:) = [x y];
            c(end,:) = [0 0 0];
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            s = app.NodeNameEditField.Value;
            n = app.unique_num(app.node_connections);
            app.node_text(end+1) = text(app.UIAxes,app.Node_pos(end,1),app.Node_pos(end,2)+2,int2str(n),'Color','blue','FontSize',12,'tag',sprintf('cell%i',n));
            % Get the attributes for the new node
            type_node = app.NodeTypeDropDown.Value;
            atts = app.node_preset_dict(app.NodePresetsDropDown.Value);
            atts = atts{1};
            atts(45:46)= [x y];            
            % Save the new nodes attributes in the correct arrays 
            app.raw_nodes(end+1,:) = [s type_node n num2cell(atts)];
            app.raw_nodes_reserve(end+1,:) = [s type_node n num2cell(atts)];
            app.node_connections(end+1)=n;
        end

        % Button pushed function: CancelButton_2
        function cancelNodeSave(app, event)
            
            % Removing the node from the graph
            c = get(app.node_pos_plot,'CData');
            c(end,:) = [];
            
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.SelectLocationButton.Enable = "on";
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
        end

        % Button pushed function: DeleteNodeButton
        function deleteNode(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";
            
            % 1. Check for overlapping indices
            if app.editnodeind == app.delnodeind
                set(app.NodeNumberEditField,'Value',app.NodeNumberEditField.Placeholder);
                set(app.NodeTypeEditField,'Value',app.NodeTypeEditField.Placeholder)
                set(app.AttributeValueEditField,'Value',0.0);%app.AttributeValueEditField.Placeholder);
                app.SelectNodeButton_2.Enable = "on";
                app.ResetButton.Enable = "off";
                app.UpdateButton.Enable = "off";
                app.PlotAPButton.Enable = "off";
                app.editnodeind = 0;
            end
            if app.delnodeind == app.node1ind
                set(app.node1path, 'String', 'N/A');      
                b = findobj('tag','savepath');
                b.Enable = 'off';
                app.node1ind = 0;
            end
            if app.delnodeind == app.node2ind
                set(app.node2path, 'String', 'N/A');       
                b = findobj('tag','savepath');
                b.Enable = 'off';
                app.node2ind = 0;
            end
            % 2. Delete the node physically from the plot
            app.Node_pos(app.delnodeind,:) = [];
            app.node_connections(app.delnodeind) = [];
            c = get(app.node_pos_plot,'CData');
            c(app.delnodeind,:) = [];
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            % Delete the node from the arrays - requires finding the exact
            % node index as per the raw array
            true_index = app.raw_nodes{app.delnodeind+1,3};
            partial1 =[app.raw_nodes{2:end,3}].';
            [index1,~] = find(partial1 == true_index);
            delete(app.node_text(index1))
            index1 = index1+1;            
            app.raw_nodes(index1,:) = [];
            app.raw_nodes_reserve(index1,:) = [];

            % 3. Delete the paths connected to the node being deleted
            partial1 =[app.raw_paths{2:end,3}; app.raw_paths{2:end,4}].';
            [index1,~] = find(partial1 == true_index);
            index_visual=index1;
            % Selecting multiple paths to delete from a row
            delete(app.path_plot([app.path_connections([index_visual])]))
            % alternatively this could also be an issue, try contains(array,pattern) instead
            if ismember(app.path_ind_visual,app.path_connections([index_visual],1))
                p = findobj('tag','selectpathdel');
                p.Enable = 'on'; 
                p = findobj('tag', 'cancelpathdel');
                p.Enable = 'off';
                p = findobj('tag','delpath');
                p.Enable = 'off';       
                app.path_ind_visual = 0;
            end
            if ismember(app.editpathind,app.path_connections([index_visual],1))
                app.firsttime_path = true;
                app.editpathind = 0;
            end
            app.path_connections([index_visual],:) = [];
            index1 = index1+1;
            app.raw_paths(index1,:) = [];
            app.raw_paths_reserve(index1,:) = [];
            app.delnodeind = 0;
            delete(app.tooltipObj)
            drawnow update;
        end

        % Button pushed function: CancelButton
        function cancelNodeDel(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";
            app.SelectNodeButton.Enable = "on";

            c = get(app.node_pos_plot,'CData');
            color = [1 0 0];        
            [q, idx] = ismember(color,c,'rows');
            if q
                if(idx == app.editnodeind)
                    c(idx,:) = [0 0 1];
                elseif (idx == app.node1ind)
                    c(idx,:) = [1 1 0];
                elseif(idx == app.node2ind)
                    c(idx,:) = [1 0 1];
                else
                    c(idx,:) = [0 0 0];
                end
            end
            set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.delnodeind = 0;

        end

        % Button pushed function: SelectNetworkConfigFileButton
        function selectConfigFile(app, event)
            
            % UI window allowing the operator to browse for and select a data file
            [fileName,filePath] = uigetfile('*.mat','Load Heart Configuration File');
            f = fullfile(filePath,fileName);
            app.CurrentConfigFileEditField.Value = fileName;           
            
            dim=load(f);

            % TO ADD: Will need to reset the following
            % app.raw_nodes => dim.Node_para gives the parameter names
            %               => dim.Node_Raw gives the rest below it
            % app.raw_paths => dim.Path_para gives the parameter names
            %               => dim.Path_Raw gives the rest below it
            % app.raw_probes => dim.Probes_raw

            [NumNodes,~]=size(dim.Node);
            [NumPaths,~]=size(dim.Path);
            app.Node_pos=dim.Node_pos;
            app.Probe_pos=dim.Probe_pos;
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                c=zeros(NumNodes,3);
                set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);%'ZData',app.Node_pos(:,3),'CData',c);
                
                for n=1:NumNodes
                    text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),'Color','blue','FontSize',12,'tag',sprintf('cell%i',n));
                end
                app.node_connections = 1:NumNodes;
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2));%,'ZData',app.Probe_pos(:,3));
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_connections = [1:NumPaths.'; dim.Path(:,1).'; dim.Path(:,2).'].';
            for n=1:NumPaths
                app.path_plot(n)=line([app.Node_pos(app.path_connections(n,1),1),app.Node_pos(app.path_connections(n,2),1)],...
                    [app.Node_pos(app.path_connections(n,1),2),app.Node_pos(app.path_connections(n,2),2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n));
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            I = imread('heartoutline.jpg'); 
            x = [20 180];
            y = [28 192];
            I = flipdim(I, 1);
            h= image(app.UIAxes,x,y,I); 
            uistack(h,'bottom')
            hold on;
        end

        % Button pushed function: SaveNetworkandQuitButton
        function saveNetwork(app, event)
            %TO ADD: PreBuild_unified connectivity
            delete(app);
            clc;
        end

        % Value changed function: AttributeDropDown
        function editAttrChange(app, event)
            exact_match = strcmp(app.raw_nodes(1,:), app.AttributeDropDown.Value);
            idx = find(exact_match);
            if ~app.editnodeind
                set(app.AttributeValueEditField,'Value',0);
            else                
                app.AttributeValueEditField.Value = app.raw_nodes{app.editnodeind+1,idx};
            end
            drawnow update;
        end

        % Button pushed function: PlotAPButton
        function plotAPtrace(app, event)
            
        end

        % Button pushed function: SelectNode1Button
        function selectPathNode1(app, event)
            
        end

        % Button pushed function: SelectNode2Button
        function selectPathNode2(app, event)
            
        end

        % Button pushed function: SelectPathButton
        function selectPathDel(app, event)
            
        end

        % Button pushed function: DeletePathButton
        function deletePath(app, event)
            
        end

        % Button pushed function: CancelPathDeletionButton
        function cancelPathDel(app, event)
            
        end

        % Button pushed function: CancelPathCreationButton
        function cancelPathSave(app, event)
            
        end

        % Button pushed function: SavePathButton
        function savePath(app, event)
            
        end

        % Button pushed function: SelectPathButton_2
        function selectPathEdit(app, event)
            
        end

        % Value changed function: AttributeDropDown_2
        function editPathChange(app, event)
            value = app.AttributeDropDown_2.Value;
            
        end

        % Button pushed function: UpdateButton_2
        function updatePathAttr(app, event)
            
        end

        % Button pushed function: ResetButton_2
        function resetPathAttr(app, event)
            
        end

        % Button pushed function: UpdateButton
        function updateNodeAttr(app, event)
            
        end

        % Button pushed function: ResetButton
        function resetNodeAttr(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.NodeCreationPanel = uipanel(app.NodesTab);
            app.NodeCreationPanel.Title = 'Node Creation';
            app.SelectLocationButton = uibutton(app.NodeCreationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectLocation, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.NodeTypeDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodeTypeDropDownLabel.Text = 'Node Type';
            app.NodeTypeDropDown = uidropdown(app.NodeCreationPanel);
            app.NodeTypeDropDown.Items = {'N', 'M', 'NM'};
            app.NodeTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.ClickedFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.Value = 'N';
            app.NodePresetsDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodePresetsDropDownLabel.Text = 'Node Presets';
            app.NodePresetsDropDown = uidropdown(app.NodeCreationPanel);
            app.NodePresetsDropDown.Items = {'N/A'};
            app.NodePresetsDropDown.Value = 'N/A';
            app.SaveNodeButton = uibutton(app.NodeCreationPanel, 'push');
            app.SaveNodeButton.ButtonPushedFcn = createCallbackFcn(app, @saveNode, true);
            app.SaveNodeButton.Enable = 'off';
            app.SaveNodeButton.Text = 'Save Node';
            app.CancelButton_2 = uibutton(app.NodeCreationPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeSave, true);
            app.CancelButton_2.Enable = 'off';
            app.CancelButton_2.Text = 'Cancel';
            app.NodeNameEditFieldLabel = uilabel(app.NodeCreationPanel);
            app.NodeNameEditFieldLabel.Text = 'Node Name';
            app.NodeNameEditField = uieditfield(app.NodeCreationPanel, 'text');
            app.NodeNameEditField.Placeholder = '[New Cell Name]';
            app.NodeDeletionPanel = uipanel(app.NodesTab);
            app.NodeDeletionPanel.Title = 'Node Deletion';
            app.SelectNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.SelectNodeButton.ButtonPushedFcn = createCallbackFcn(app, @selectNodeDel, true);
            app.SelectNodeButton.Text = 'Select Node';
            app.CancelButton = uibutton(app.NodeDeletionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeDel, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.DeleteNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteNode, true);
            app.DeleteNodeButton.Enable = 'off';
            app.DeleteNodeButton.Text = 'Delete Node';
            app.EditNodePanel = uipanel(app.NodesTab);
            app.EditNodePanel.Title = 'Edit Node';
            app.SelectNodeButton_2 = uibutton(app.EditNodePanel, 'push');
            app.SelectNodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectNodeEdit, true);
            app.SelectNodeButton_2.Text = 'Select Node';
            app.AttributeDropDownLabel = uilabel(app.EditNodePanel);
            app.AttributeDropDownLabel.Text = 'Attribute';
            app.AttributeDropDown = uidropdown(app.EditNodePanel);
            app.AttributeDropDown.Items = {'N/A'};
            app.AttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @editAttrChange, true);
            app.AttributeDropDown.Placeholder = 'N/A';
            app.AttributeDropDown.Value = 'N/A';
            app.UpdateButton = uibutton(app.EditNodePanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @updateNodeAttr, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.AttributeValueEditFieldLabel = uilabel(app.EditNodePanel);
            app.AttributeValueEditFieldLabel.Text = 'Attribute Value';
            app.AttributeValueEditField = uieditfield(app.EditNodePanel, 'numeric');
            app.AttributeValueEditField.ValueDisplayFormat = '%.3f';
            app.AttributeValueEditField.Placeholder = '0.0';
            app.ResetButton = uibutton(app.EditNodePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetNodeAttr, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.NodeNumberEditFieldLabel = uilabel(app.EditNodePanel);
            app.NodeNumberEditFieldLabel.Text = 'Node (Number)';
            app.NodeNumberEditField = uieditfield(app.EditNodePanel, 'text');
            app.NodeNumberEditField.Editable = 'off';
            app.NodeNumberEditField.Placeholder = '[No node selected]';
            app.PlotAPButton = uibutton(app.EditNodePanel, 'push');
            app.PlotAPButton.ButtonPushedFcn = createCallbackFcn(app, @plotAPtrace, true);
            app.PlotAPButton.Enable = 'off';
            app.PlotAPButton.Text = 'Plot AP';
            app.NodeTypeEditFieldLabel = uilabel(app.EditNodePanel);
            app.NodeTypeEditFieldLabel.Text = 'Node Type';
            app.NodeTypeEditField = uieditfield(app.EditNodePanel, 'text');
            app.NodeTypeEditField.Editable = 'off';
            app.NodeTypeEditField.Placeholder = 'N/A';
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.Title = 'Paths';
            app.EditPathPanel = uipanel(app.PathsTab);
            app.EditPathPanel.Title = 'Edit Path';
            app.Node1iLabel = uilabel(app.EditPathPanel);
            app.Node1iLabel.Text = 'Node 1 (i):';
            app.Node1iEditField = uieditfield(app.EditPathPanel, 'text');
            app.Node1iEditField.Editable = 'off';
            app.Node2jLabel = uilabel(app.EditPathPanel);
            app.Node2jLabel.Text = 'Node 2 (j):';
            app.Node2jEditField = uieditfield(app.EditPathPanel, 'text');
            app.Node2jEditField.Editable = 'off';
            app.SelectPathButton_2 = uibutton(app.EditPathPanel, 'push');
            app.SelectPathButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectPathEdit, true);
            app.SelectPathButton_2.Text = 'Select Path';
            app.UpdateButton_2 = uibutton(app.EditPathPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @updatePathAttr, true);
            app.UpdateButton_2.Enable = 'off';
            app.UpdateButton_2.Text = 'Update';
            app.AttributeDropDown_2Label = uilabel(app.EditPathPanel);
            app.AttributeDropDown_2Label.Text = 'Attribute';
            app.AttributeDropDown_2 = uidropdown(app.EditPathPanel);
            app.AttributeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @editPathChange, true);
            app.AttributeValueEditField_2Label = uilabel(app.EditPathPanel);
            app.AttributeValueEditField_2Label.Text = 'Attribute Value';
            app.AttributeValueEditField_2 = uieditfield(app.EditPathPanel, 'numeric');
            app.ResetButton_2 = uibutton(app.EditPathPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetPathAttr, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.PathDeletionPanel = uipanel(app.PathsTab);
            app.PathDeletionPanel.Title = 'Path Deletion';
            app.SelectPathButton = uibutton(app.PathDeletionPanel, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @selectPathDel, true);
            app.SelectPathButton.Text = 'Select Path';
            app.DeletePathButton = uibutton(app.PathDeletionPanel, 'push');
            app.DeletePathButton.ButtonPushedFcn = createCallbackFcn(app, @deletePath, true);
            app.DeletePathButton.Enable = 'off';
            app.DeletePathButton.Text = 'Delete Path';
            app.CancelPathDeletionButton = uibutton(app.PathDeletionPanel, 'push');
            app.CancelPathDeletionButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPathDel, true);
            app.CancelPathDeletionButton.Enable = 'off';
            app.CancelPathDeletionButton.Text = 'Cancel Path Deletion';
            app.PathCreationPanel = uipanel(app.PathsTab);
            app.PathCreationPanel.Title = 'Path Creation';
            app.Node1iLabel_2 = uilabel(app.PathCreationPanel);
            app.Node1iLabel_2.Text = 'Node 1 (i):';
            app.Node1iEditField_2 = uieditfield(app.PathCreationPanel, 'text');
            app.Node1iEditField_2.Editable = 'off';
            app.Node2jLabel_2 = uilabel(app.PathCreationPanel);
            app.Node2jLabel_2.Text = 'Node 2 (j):';
            app.Node2jEditField_2 = uieditfield(app.PathCreationPanel, 'text');
            app.Node2jEditField_2.Editable = 'off';
            app.SelectNode1Button = uibutton(app.PathCreationPanel, 'push');
            app.SelectNode1Button.ButtonPushedFcn = createCallbackFcn(app, @selectPathNode1, true);
            app.SelectNode1Button.Text = 'Select Node 1';
            app.SelectNode2Button = uibutton(app.PathCreationPanel, 'push');
            app.SelectNode2Button.ButtonPushedFcn = createCallbackFcn(app, @selectPathNode2, true);
            app.SelectNode2Button.Text = 'Select Node 2';
            app.SavePathButton = uibutton(app.PathCreationPanel, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @savePath, true);
            app.SavePathButton.Enable = 'off';
            app.SavePathButton.Text = 'Save Path';
            app.CancelPathCreationButton = uibutton(app.PathCreationPanel, 'push');
            app.CancelPathCreationButton.ButtonPushedFcn = createCallbackFcn(app, @cancelPathSave, true);
            app.CancelPathCreationButton.Enable = 'off';
            app.CancelPathCreationButton.Text = 'Cancel Path Creation';
            app.PathPresetDropDownLabel = uilabel(app.PathCreationPanel);
            app.PathPresetDropDownLabel.Text = 'Path Preset';
            app.PathPresetDropDown = uidropdown(app.PathCreationPanel);
            app.ProbesTab = uitab(app.TabGroup);
            app.ProbesTab.Title = 'Probes';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.NetworkTab = uitab(app.TabGroup2);
            app.NetworkTab.Title = 'Network';
            app.UIAxes = uiaxes(app.NetworkTab);
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Y (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SingleCellTab = uitab(app.TabGroup2);
            app.SingleCellTab.Title = 'Single Cell';
            app.UIAxes2 = uiaxes(app.SingleCellTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadandSavePanel = uipanel(app.UIFigure);
            app.LoadandSavePanel.Title = 'Load and Save';
            app.SelectNetworkConfigFileButton = uibutton(app.LoadandSavePanel, 'push');
            app.SelectNetworkConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectConfigFile, true);
            app.SelectNetworkConfigFileButton.Text = 'Select Network Config File';
            app.CurrentConfigFileEditFieldLabel = uilabel(app.LoadandSavePanel);
            app.CurrentConfigFileEditFieldLabel.Text = 'Current Config File';
            app.CurrentConfigFileEditField = uieditfield(app.LoadandSavePanel, 'text');
            app.CurrentConfigFileEditField.Editable = 'off';
            app.CurrentConfigFileEditField.Placeholder = '[No current file]';
            app.SaveNetworkandQuitButton = uibutton(app.LoadandSavePanel, 'push');
            app.SaveNetworkandQuitButton.ButtonPushedFcn = createCallbackFcn(app, @saveNetwork, true);
            app.SaveNetworkandQuitButton.Text = 'Save Network and Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorial_edit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end